classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button                   matlab.ui.control.Button
        Button2                  matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        Label2                   matlab.ui.control.Label
        Button3                  matlab.ui.control.Button
        DiffusionEquationWithVariableCoefficientButton  matlab.ui.control.Button
        HelpButton               matlab.ui.control.Button
        ClickforsyntaxhelpLabel  matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause(2);
            a='This  simple  desktop application  will  solve  parabolic  diffusion equaions  and  variable coefficient paraboilic diffusion equation using finite difference method and will show the result numerically as well as graphically. Thank you for using it.';
            b='';
            for i=1:length(a)
                b(i)=a(i);
                app.TextArea.Value=b;
                pause(0.0025);
            end
        end

        % Button pushed function: Button
        function Crank(app, event)
            my_ob=select_method();
            my_ob.type=1;
        end

        % Button pushed function: Button2
        function forward(app, event)
            my_ob=select_method();
            my_ob.type=2;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
        end

        % Button pushed function: Button3
        function ex(app, event)
            clear;
            clc;
            closereq;
        end

        % Button pushed function: 
        % DiffusionEquationWithVariableCoefficientButton
        function DiffusionEquationWithVariableCoefficientButtonPushed(app, event)
            v_crank_nicholson();
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            help_syntax();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Crank, true);
            app.Button.Text = 'Crank Nicolson Method';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @forward, true);
            app.Button2.Text = 'Forward Explicit Method';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' Choose a method';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '     Finit Difference  Method For  1 Dimensional Parabolic Diffusion Equation     ';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @ex, true);
            app.Button3.Text = 'Exit';
            app.DiffusionEquationWithVariableCoefficientButton = uibutton(app.UIFigure, 'push');
            app.DiffusionEquationWithVariableCoefficientButton.ButtonPushedFcn = createCallbackFcn(app, @DiffusionEquationWithVariableCoefficientButtonPushed, true);
            app.DiffusionEquationWithVariableCoefficientButton.Text = 'Diffusion Equation With Variable Coefficient';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help ';
            app.ClickforsyntaxhelpLabel = uilabel(app.UIFigure);
            app.ClickforsyntaxhelpLabel.Text = 'Click for syntax help';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end