classdef v_crank_nicholson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Label      matlab.ui.control.Label
        Label2     matlab.ui.control.Label
        Label3     matlab.ui.control.Label
        Label4     matlab.ui.control.Label
        Label5     matlab.ui.control.Label
        Label6     matlab.ui.control.Label
        Label7     matlab.ui.control.Label
        Label8     matlab.ui.control.Label
        Label9     matlab.ui.control.Label
        Label10    matlab.ui.control.Label
        fx         matlab.ui.control.EditField
        g1t        matlab.ui.control.EditField
        g2t        matlab.ui.control.EditField
        alp        matlab.ui.control.EditField
        i_len      matlab.ui.control.EditField
        f_len      matlab.ui.control.EditField
        i_time     matlab.ui.control.EditField
        f_time     matlab.ui.control.EditField
        len_step   matlab.ui.control.EditField
        time_step  matlab.ui.control.EditField
        generate   matlab.ui.control.Button
        Label11    matlab.ui.control.Label
        clear      matlab.ui.control.Button
        Label12    matlab.ui.control.Label
        exit       matlab.ui.control.Button
        TextArea   matlab.ui.control.TextArea
        Button     matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
    end

    properties (Access = public)
        % Description
       
    end

    methods (Access = public)
    
        function print(app, a)
            b='';
            for i=1:length(a)
                b(i)=a(i);
                app.TextArea.Value=b;
                pause(0.025);
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.g1t.Value='0';
            app.g2t.Value='0';
            
            app.i_len.Value='0';
            app.f_len.Value='1';
            app.i_time.Value='0';
            app.f_time.Value='';
            app.len_step.Value='';
            app.time_step.Value='';
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            app.fx.Value='';
            app.g1t.Value='';
            app.g2t.Value='';
            app.alp.Value='';
            app.i_len.Value='';
            app.f_len.Value='';
            app.i_time.Value='';
            app.f_time.Value='';
            app.len_step.Value='';
            app.time_step.Value='';
            app.TextArea.Value='';
        end

        % Button pushed function: exit
        function exitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: generate
        function generateButtonPushed(app, event)

            if(isempty(app.fx.Value)|| isempty(app.g1t.Value)|| isempty(app.g2t.Value) ||...
                    isempty(app.alp.Value)||isempty(app.i_len.Value)||isempty(app.f_len.Value)||...
                    isempty(app.i_time.Value)||isempty(app.f_time.Value)||isempty(app.len_step.Value)||isempty(app.time_step.Value))
               
                a='Sorry. Your entry is not valid!! Please give the correct data.';
                
                print(app, a);
                                            
                return;
                    
            end


            try
                
                if(eval(app.i_time.Value) > eval(app.f_time.Value))                    
                    
                    a='Sorry. Invalid entry. Check initial & final time.';
                    
                    print(app, a);
                    
                    return;
                end
                
                f=str2func(['@(x) ',app.fx.Value]); % Description
                g1=str2func(['@(t) ', app.g1t.Value]);
                g2=str2func(['@(t) ', app.g2t.Value]);
                alpha=str2func(['@(x,t) ', app.alp.Value]);
                x0=eval(app.i_len.Value);
                xn=eval(app.f_len.Value);
                t0=eval(app.i_time.Value);
                tn=eval(app.f_time.Value);
                h=eval(app.len_step.Value);
                k=eval(app.time_step.Value);
                
                f(x0+h);
                g1(t0+k);
                g2(t0+k);
                alpha(x0+h, t0+k);
                
                if(alpha(x0+h, t0+k)==0)
                    
                    a='Sorry! α(x,t) must not be non-zero!';
                    
                    print(app, a);
                
                    return;
                end 
                
            catch
                
                a='Sorry! Input error! Please correctly enter the input.';
                
                print(app, a);
                
                return;
            end


            pause(1);
            app.TextArea.Value='Analysing. Wait . . .';
            
            pause(1);
            b='';
            for s=1:3
                
                a='. . .';
                for i=1:length(a)
                    b(i)=a(i);
                    app.TextArea.Value=b;
                    pause(0.25);
                end
                app.TextArea.Value='';
                b='';
            end


            a='Result has been generated. Thank You.';
            
            print(app, a);
                                  
            
            %-------
            
            r=(k/h^2);
            n= fix(abs(xn-x0)/h +1e-14);
            m= fix(abs(tn-t0)/k +1e-14);
            
            
            t=zeros(1, m);
            
            for i=1:m
                t(i)=t0+i*k;
            end
            
            x=zeros(1, n-1);
            
            for i=1:n-1
                x(i)=x0+i*h;
            end
            
            A=zeros(n-1, n-1);
            
            B=zeros(n-1, n-1);
            
            
            u0=zeros(1, m);
            un=zeros(1, m);
            
            for i=1:m
                u0(i)=g1(t(i));
                un(i)=g2(t(i));
            end
            
            
            uj=zeros(1, n-1);
            
            for i=1:n-1
                uj(i)=f(x(i));
            end
            
            uj0=f(x0);
            ujn=f(xn);


            upp=zeros(m+1, n+1);
            upp(1, 1)=f(x0);
            upp(1, n+1)=f(xn);
            
            for i=2:n
                upp(1, i)=f(x(i-1));
            end
      
            
            l=1;
            j=2;
            while(l<=m)
                
                
                for i=1:n-1
                    A(i, i)=2+2*r*alpha(x(i), t(l) );
                    B(i, i)=2-2*r*alpha(x(i), t(l) );
                    if(i>=2)
                        A(i-1, i)=-r*alpha(x(i-1), t(l));
                        A(i, i-1)=-r*alpha(x(i), t(l) );
                        B(i-1, i)=r*alpha(x(i-1), t(l) );
                        B(i, i-1)=r*alpha(x(i), t(l) );
                    end
                end
                
                
                b = B*uj';
                
                b(1, 1)= b(1, 1)+ alpha(x(1), t(l) )*r*u0(l)+ alpha(x(1), t(l))*r*uj0;
                b(n-1, 1)= b(n-1, 1) + alpha(x(n-1), t(l))*r* un(l) + alpha(x(n-1), t(l) )*r*ujn;
                
                uj0=u0(l);
                ujn=un(l);
                
                u=lu_system(A, b, n-1);
                
                uj=u;
     
                
                upp(j,1)= g1(t(l));
                upp(j,n+1)=g2(t(l));
                for i=2:n
                    upp(j,i)=u(i-1);
                end
            
                l=l+1;
                j=j+1;
                
                
            end
            
            xx=zeros(1,n+1);
            
            xx(1)=x0;
            for i=2:n+1
                xx(i)=x0 +(i-1)*h;
            end
            
            
            ob=v_crank_table();
            ob.t_fx=f;
            ob.t_g1=g1;
            ob.t_g2=g2;
            ob.xu=xx;
            ob.up=upp(m+1, :);
            ob.uu=upp;
            ob.t_h=h;
            ob.t_k=k;
            ob.t_x0=x0;
            ob.t_xn=xn;
            ob.t_t0=t0;
            ob.t_tn=tn;
            ob.t_alpha=alpha;
            ob.table_results();
         
            
        end

        % Callback function
        function show_tableButtonPushed(app, event)
          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' f(x)      = ';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'g1(t)     = ';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'g2(t)     = ';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Initial length           :';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Final length            :';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Initial time              :';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Final time               :';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'Length step size   :';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Time step size       :';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'α(x,t)    = ';
            app.fx = uieditfield(app.UIFigure, 'text');
            app.g1t = uieditfield(app.UIFigure, 'text');
            app.g2t = uieditfield(app.UIFigure, 'text');
            app.alp = uieditfield(app.UIFigure, 'text');
            app.i_len = uieditfield(app.UIFigure, 'text');
            app.f_len = uieditfield(app.UIFigure, 'text');
            app.i_time = uieditfield(app.UIFigure, 'text');
            app.f_time = uieditfield(app.UIFigure, 'text');
            app.len_step = uieditfield(app.UIFigure, 'text');
            app.time_step = uieditfield(app.UIFigure, 'text');
            app.generate = uibutton(app.UIFigure, 'push');
            app.generate.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generate.Text = 'Generate';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'Press ''Generate''  to generate result';
            app.clear = uibutton(app.UIFigure, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = 'Clear';
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = '    Parabolic Diffusion Equation With Variable Coefficient';
            app.exit = uibutton(app.UIFigure, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exit.Text = 'Close';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'icon1v.PNG';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = v_crank_nicholson

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end