classdef v_crank_table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        closeButton                   matlab.ui.control.Button
        plot1                         matlab.ui.control.UIAxes
        SelectanytimelevelLabel       matlab.ui.control.Label
        select_time                   matlab.ui.control.DropDown
        SelectplottypeDropDownLabel   matlab.ui.control.Label
        selectplot                    matlab.ui.control.DropDown
        EnterLengthButton             matlab.ui.control.Button
        ClickFindtogettheresultLabel  matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        FindButton                    matlab.ui.control.Button
        TemperatureButton             matlab.ui.control.Button
        length_text                   matlab.ui.control.EditField
        heat_text                     matlab.ui.control.EditField
    end


    properties (Access = public)
        uu, xu, up, t_x0, t_xn, t_t0 , t_tn, t_h, t_k, t_alpha, t_fx, t_g1, t_g2 , flag; % Description
        
        minu, maxu, mint, maxt, tab;
    end

    methods (Access = public)
    
        function  table_results(app)
            
            t=app.t_t0 + app.t_k :app.t_k:app.t_tn;
            app.select_time.Items=string(fliplr(t));
            app.selectplot.Value='Length Vs Temperature';
            
            x=app.xu;
            u=app.up;
            i=1:1:length(x);
            dat=[i',x',u'];
            app.tab=dat;
            app.UITable.Data=dat;
            
            app.plot1.Title.String = 'Length Vs Temperature';
            app.plot1.XLabel.String = 'Length';
            app.plot1.YLabel.String = 'Temperature';
            
            app.maxu=max(max(app.uu));
            app.minu=min(min(app.uu));
            app.mint=app.t_t0;
            app.maxt=app.t_tn;
            
            x=app.t_x0:app.t_h:app.t_xn;
   
            app.plot1.XLim = [app.t_x0  app.t_xn+0.001];
            app.plot1.YLim = [app.minu  app.maxu+0.001];
            
            plot(app.plot1, x, u , 'r');

            app.flag=1;
            
            
        end


    %----------------
        function crank(app)
            f=app.t_fx;
            g1=app.t_g1;
            g2=app.t_g2;
            x0=app.t_x0;
            xn=app.t_xn;
            t0=app.t_t0;
            tn=app.t_tn;
            alpha=app.t_alpha;
            h=app.t_h;
            k=app.t_k;
            
            r=(k/h^2);
            n= fix(abs(xn-x0)/h +1e-14);
            m= fix(abs(tn-t0)/k +1e-14);
            
            t=zeros(1, m);
            
            for i=1:m
                t(i)=t0+i*k;
            end
            
            x=zeros(1, n-1);
            
            for i=1:n-1
                x(i)=x0+i*h;
            end
            
            A=zeros(n-1, n-1);
            
            B=zeros(n-1, n-1);
           
            u0=zeros(1, m);
            un=zeros(1, m);
            
            for i=1:m
                u0(i)=g1(t(i));
                un(i)=g2(t(i));
            end
            
            uj=zeros(1, n-1);
            
            for i=1:n-1
                uj(i)=f(x(i));
            end
            
            uj0=f(x0);
            ujn=f(xn);


            upp=zeros(m+1, n+1);
            upp(1, 1)=f(x0);
            upp(1, n+1)=f(xn);
            
            for i=2:n
                upp(1, i)=f(x(i-1));
            end
      
            
            l=1;
            j=2;
            while(l<=m)
                
                for i=1:n-1
                    A(i, i)=2+2*r*alpha(x(i), t(l) );
                    B(i, i)=2-2*r*alpha(x(i), t(l) );
                    if(i>=2)
                        A(i-1, i)=-r*alpha(x(i-1), t(l));
                        A(i, i-1)=-r*alpha(x(i), t(l) );
                        B(i-1, i)=r*alpha(x(i-1), t(1) );
                        B(i, i-1)=r*alpha(x(i), t(l) );
                    end
                end
                
                b = B*uj';
                
                b(1, 1)= b(1, 1)+ alpha(x(1), t(l) )*r*u0(l)+ alpha(x(1), t(l))*r*uj0;
                b(n-1, 1)= b(n-1, 1) + alpha(x(n-1), t(l))*r* un(l) + alpha(x(n-1), t(l) )*r*ujn;
                
                uj0=u0(l);
                ujn=un(l);
                
                u=lu_system(A, b, n-1);
                
                uj=u;
     
                
                upp(j,1)= g1(t(l));
                upp(j,n+1)=g2(t(l));
                for i=2:n
                    upp(j,i)=u(i-1);
                end
            
                l=l+1;
                j=j+1;
                
                
            end
            
            app.up=upp(m+1,:);
            app.uu=upp;


            table(app);


            %-----


        end
        
        %-------
    
        function table(app)
            
            x=app.xu;
            u=app.up;
            i=1:1:length(x);
            dat=[i',x',u'];
            app.tab=dat;
            app.UITable.Data=dat;
            
            if (app.flag==1)
                l_vs_t_plot(app);
            elseif (app.flag==2)
                    m_plot(app);
            end
            
        end
    
    
        %------
        
        function  l_vs_t_plot(app)
            
            cla(app.plot1,'reset');
            
            x=app.xu;
            u=app.up;
            
            app.plot1.Title.String = 'Length Vs Temperature';
            app.plot1.XLabel.String = 'Length';
            app.plot1.YLabel.String = 'Temperature';

            
            %x=app.t_x0:app.t_h:app.t_xn;
            app.plot1.XLim = [app.t_x0  app.t_xn+0.001];
            app.plot1.YLim = [app.minu  app.maxu+0.001];
            
            plot(app.plot1, x ,u , 'r');
               
            
        end
    
    
        %------------


        function m_plot(app)
            
            
            cla(app.plot1,'reset')
            
            x0=app.t_x0;
            xn=app.t_xn;
            t0=app.t_t0;
            tn=app.t_tn;
            h=app.t_h;
            k=app.t_k;
            
            n= fix(abs(xn-x0)/h +1e-14);
            m= fix(abs(tn-t0)/k +1e-14);
            
           
            app.plot1.XLim = [x0  xn+0.001];
            app.plot1.YLim = [app.mint  app.maxt+0.001];

            
            xx=zeros(1, n+1);
            
            xx(1)=x0;
            for i=2:n+1
                xx(i)=x0 +(i-1)*h;
            end
            
            tt=zeros(1, m+1);
            
            tt(1)=t0;
            for i=2:m+1
                tt(i)=t0 +(i-1)*k;
            end
            
            
            [x_mesh, t_mesh ]=meshgrid(xx,tt);


            umesh=zeros(m+1, n+1);
            
            
            app.plot1.Title.String = 'Meshplot: Length Vs Time VS Temperature';
            app.plot1.XLabel.String = 'Length';
            app.plot1.YLabel.String = 'Time';
            app.plot1.ZLabel.String = 'Temperature';
            
            rotate3d(app.plot1,'on');
            
            for i=1:m+1
                
                umesh(i,:)=app.uu(i, :);
                mesh(app.plot1, x_mesh, t_mesh, umesh);
                %set(h,'LineStyle','none');
                %rotate3d(app.plot1,'on');
                c=colorbar(app.plot1);
                colormap(app.plot1,'jet');
                c.Label.String='Color Label';
                pause(0.005);
                % hold on;
            end


        end
        
    end


    methods (Access = private)

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            closereq;
        end

        % Value changed function: select_time
        function select_timeValueChanged(app, event)
            value = app.select_time.Value;
            
            app.t_tn=str2double(value);
            
            %plot(app.plot1, 0, 0);
            crank(app);
            
        end

        % Value changed function: selectplot
        function selectplotValueChanged(app, event)
            value = app.selectplot.Value;
            
            switch value
                case 'Length Vs Temperature'
                    app.flag=1;
                    l_vs_t_plot(app);
                case 'Mesh Plot'
                    app.flag=2;
                    m_plot(app);
            end
            
            
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            try
                xx=eval(app.length_text.Value);
                
                              
                if(xx < app.t_x0)
                    a='Input must be greater then initia length.';
                    b='';
                    for i=1:length(a)
                        b(i)=a(i);
                        app.heat_text.Value=b;
                        pause(0.025);
                    end
                    return;
                end
                
                %n=fix( (abs(app.t_xn-app.t_x0)/app.t_h) + 1e-14  );
                
                
                nn =fix((abs(xx-app.t_x0)/app.t_h)+ 1e-14 );
                
                
                if( xx >app.t_xn  )
                    
                    a='Your input is larger then final length';
                    b='';
                    for i=1:length(a)
                        b(i)=a(i);
                        app.heat_text.Value=b;
                        pause(0.025);
                    end
                    return;
                    
                end
                
       
               ss=num2str(app.tab(nn+1, 3), '%.8f');
   
               b='';
               for i=1:length(ss)
                   b(i)=ss(i);
                   app.heat_text.Value=b;
                   pause(0.025);
               end
                
                
           catch
                a='Input Error!. Please Correct. ';
                b='';
                for i=1:length(a)
                    b(i)=a(i);
                    app.heat_text.Value=b;
                    pause(0.025);
                end
           end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'Length (x)'; 'Temperature'};
            app.UITable.RowName = {};
            app.closeButton = uibutton(app.UIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            app.plot1 = uiaxes(app.UIFigure);
            app.SelectanytimelevelLabel = uilabel(app.UIFigure);
            app.SelectanytimelevelLabel.Text = 'Select any time level :';
            app.select_time = uidropdown(app.UIFigure);
            app.select_time.ValueChangedFcn = createCallbackFcn(app, @select_timeValueChanged, true);
            app.SelectplottypeDropDownLabel = uilabel(app.UIFigure);
            app.SelectplottypeDropDownLabel.Text = 'Select plot type:';
            app.selectplot = uidropdown(app.UIFigure);
            app.selectplot.Items = {'Length Vs Temperature', 'Mesh Plot'};
            app.selectplot.ValueChangedFcn = createCallbackFcn(app, @selectplotValueChanged, true);
            app.selectplot.Value = 'Length Vs Temperature';
            app.EnterLengthButton = uibutton(app.UIFigure, 'push');
            app.EnterLengthButton.Text = 'Enter Length :';
            app.ClickFindtogettheresultLabel = uilabel(app.UIFigure);
            app.ClickFindtogettheresultLabel.Text = 'Click Find to get the result';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '==>';
            app.FindButton = uibutton(app.UIFigure, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.TemperatureButton = uibutton(app.UIFigure, 'push');
            app.TemperatureButton.Text = 'Temperature =';
            app.length_text = uieditfield(app.UIFigure, 'text');
            app.heat_text = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = v_crank_table

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end